classdef AdomeCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Switch                       matlab.ui.control.Switch
        ListOfCOMPorts               matlab.ui.control.ListBox
        SerialCommands               matlab.ui.control.EditField
        TextArea                     matlab.ui.control.TextArea
        TestButton                   matlab.ui.control.Button
        TestcommandsListBox          matlab.ui.control.ListBox
        TestcommandsListBoxLabel     matlab.ui.control.Label
        ViewButton                   matlab.ui.control.Button
        AmountofLEDsEditField        matlab.ui.control.NumericEditField
        AmountofLEDsEditFieldLabel   matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        Lamp_3                       matlab.ui.control.Lamp
        Label_3                      matlab.ui.control.Label
        Lamp_2                       matlab.ui.control.Lamp
        Label_2                      matlab.ui.control.Label
        Lamp_1                       matlab.ui.control.Lamp
        Label                        matlab.ui.control.Label
        StartButton                  matlab.ui.control.StateButton
        ListofcoordinatesButton      matlab.ui.control.StateButton
        AmountofnodesEditField       matlab.ui.control.NumericEditField
        AmountofnodesEditFieldLabel  matlab.ui.control.Label
        UIAxes_2                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        calibrationCycle = 0 % 1st, 2nd or 3rd part of the cycle
        amountOfNodes = 1 % the amount of given nodes
        amountOfLeds = 3;
        numberOfTrials = 1 % the numer of trials OCS needs to perform
        JSONData;
        decodedJSONData;
        antennaArray;
        serial; % 
        CanMasterInitiated;
        JSONFile = 'Mock_JSON/Adome_Antennas.json';
        ExportJSONFile = 'Mock_JSON/Adome_Antennas_export.json';
        timer1;
        a = 0;
    end
    
    methods (Access = public)
        
        function startCalibrationCycle(app) %Start the calibration
            COMPort = app.ListOfCOMPorts.Value;
            % parse info to OCS .mex file: 
            % COM port of ADome connection ("COM1") - Number of antennas in the ADome - 
            % Number of leds per antenna (3) - Number of trials
            JSONFILE_name= sprintf(app.JSONFile);
            if exist(JSONFILE_name, 'file')==2
                fid = fopen(JSONFILE_name,'w+'); %deletes contents 
                fclose(fid);
            end
            if (app.Switch.Value == "OCS")
                app.openCOMPort(); % initialize master and check com port
                app.serial = []; % close COM port
                filename = 'OCS/OCS.exe';
                command = ['"', filename, '"',  ' ',...  
                           num2str(app.ListOfCOMPorts.Value), ' ',... 
                           num2str(app.amountOfNodes), ' ',... 
                           num2str(app.amountOfLeds), ' ',... 
                           num2str(app.numberOfTrials)]; 
                system(command) %example format "OCS/OCS.exe" COM4 1 1 1
                
            elseif (app.Switch.Value == "Mock OCS")
                mockJSON(COMPort, app.amountOfNodes, app.amountOfLeds, app.numberOfTrials);
                pause(2); %Pause the program for 2 seconds to mimic ocs delay
            end

            fid = fopen(JSONFILE_name,'r');
            verifyContents = isempty(char(fread(fid))'); %Check if the json file has data in it
            if (verifyContents == true)
                disp('ERROR: json file is empty');
            elseif (verifyContents == false)
                app.endOfCalibrationCycle();
            end
        end

        function endOfCalibrationCycle(app) %End of calibration cycle
                app.importJSONData(app.JSONFile);
                app.decodedJSONData = jsondecode(app.JSONData);
            if (app.calibrationCycle == 1)
                app.antennaArray = app.decodedJSONData;
                app.Lamp_1.Color = [0.00 1.00 0.00];
                app.StartButton.Text = 'Continue';
                app.StartButton.Enable = 'on';
            elseif(app.calibrationCycle == 2)
                app.antennaArray = overwriteFoundNodes(app.antennaArray, app.decodedJSONData);
                app.Lamp_2.Color = [0.00 1.00 0.00];
                app.StartButton.Text = 'Continue';
            elseif(app.calibrationCycle == 3)
                app.antennaArray = overwriteFoundNodes(app.antennaArray, app.decodedJSONData);
                allNodesFound = nodeVerification(app.antennaArray);
                if (allNodesFound == true)
                    app.Lamp_3.Color = [0.00 1.00 0.00];
                    app.StartButton.Text = 'Start again?';
                elseif(allNodesFound == false)
                    app.Lamp_1.Color = [1.00 0.00 0.00];
                    app.Lamp_2.Color = [1.00 0.00 0.00];
                    app.Lamp_3.Color = [1.00 0.00 0.00];
                    disp('Calibration failed, OCS did not find all nodes.')
                    app.StartButton.Text = 'Failed, retry?';
                end
            end
            app.JSONExport();
            app.StartButton.Enable = 'on';
        end
        
        function importJSONData(app, fileName) %Import and display the coordinates
                JSONFILE_name= sprintf(fileName);
                fid = fopen(JSONFILE_name,"r");
                app.JSONData = char(fread(fid))';
                fclose(fid);
        end
        
        function JSONExport(app) %exports the overwritten JSON data
            JSONFILE_name= sprintf(app.ExportJSONFile);
            fid = fopen(JSONFILE_name, "w");
            JSON = jsonencode(app.antennaArray, PrettyPrint=true);
            fprintf(fid, JSON);
            fclose(fid);
        end
    end
    
    methods (Access = private)
        function timerTriggered(app)
            app.main();
        end

        function displayCOMPorts(app)
            COMPorts = serialportlist();
            app.ListOfCOMPorts.Items = COMPorts;
        end
        
        function initiateCanMaster(app)
    	    SerialSend(app.serial, 'I');
            receivedData = SerialRead(app.serial, "ASCII");
%             disp(strjoin(string(receivedData)))
            disp(receivedData)

            while( contains(receivedData, "This system is ready to use for measurements") == false) 
                receivedData = SerialRead(app.serial, "ASCII");
            end

            disp(receivedData)
            app.CanMasterInitiated = true;
        end

        function addTimestamp(app)
            d = char(datetime(now,'ConvertFrom','datenum'));
            currentDateTime = ['[', d, ']'];
            disp(currentDateTime)
        end

        function main(app)
            if isvalid(app)
                app.displayCOMPorts();
                outputTextbox(app.TextArea); 
                scroll(app.TextArea, 'bottom')
            end
        end
        
        function openCOMPort(app)
            COMPort = app.ListOfCOMPorts.Value;
            try
                app.serial = initiateSerialPort(COMPort, 1); %number of nodes/boards = 1
            catch
                disp("can't open COM port")
                return;
            end 

            if(app.CanMasterInitiated == false)
                app.initiateCanMaster();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear outputTextbox %clear the persistent variables otherwise errors occur
            clear main
            fid = fopen("Diary/AdomeGUIOutput.log", "w"); %clear file
            fclose(fid);

            app.timer1 = timer(); %make timer
            app.timer1.TimerFcn = createCallbackFcn(app, @main); %when timer is triggered call main
            app.timer1.Period = 0.1; %set timer interval
            app.timer1.ExecutionMode = 'fixedRate';
            start(app.timer1)

            app.displayCOMPorts();
            disp('startupFcn ADomeCalibration')
            app.CanMasterInitiated = false;

            axis(app.UIAxes_2, 'equal');
            grid(app.UIAxes_2, 'on')
            xlim(app.UIAxes_2, [-1 1]);
            ylim(app.UIAxes_2, [-1 1]);
            zlim(app.UIAxes_2, [-1 1]);

            diary Diary/AdomeGUIOutput.log
            app.addTimestamp();
            disp('GUI has started.')
        end

        % Value changed function: AmountofnodesEditField
        function AmountofnodesEditFieldValueChanged(app, event)
                app.amountOfNodes = app.AmountofnodesEditField.Value;           
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            if app.AmountofnodesEditField.Value > 30
                disp('ERROR: please enter up to 30 nodes')
                app.AmountofnodesEditField.Value = 1;
            elseif app.AmountofnodesEditField.Value < 1
                disp('ERROR: please enter at least 1 node')
                app.AmountofnodesEditField.Value = 1;
            else
                app.calibrationCycle = app.calibrationCycle + 1;
                app.StartButton.Text = 'Wait';
                app.StartButton.Enable = 'off';
                if (app.calibrationCycle == 1)
                    app.Lamp_1.Color = [1.00 0.41 0.16];
                elseif(app.calibrationCycle == 2)
                    app.Lamp_2.Color = [1.00 0.41 0.16];
                elseif(app.calibrationCycle == 3)
                    app.Lamp_3.Color = [1.00 0.41 0.16];
                elseif(app.calibrationCycle == 4)
                    app.Lamp_1.Color = [0.50 0.50 0.50];
                    app.Lamp_2.Color = [0.50 0.50 0.50];
                    app.Lamp_3.Color = [0.50 0.50 0.50];
                    app.StartButton.Text = 'Start';
                    app.StartButton.Enable = 'on';
                    app.calibrationCycle = 0;
                end
                app.startCalibrationCycle();
            end 
        end

        % Value changed function: ListofcoordinatesButton
        function ListofcoordinatesButtonValueChanged(app, event)
            app.UIAxes_2.Visible = 'off';
            cla(app.UIAxes_2)
            try
                if (app.UITable.Visible == "off")
                    app.importJSONData(app.ExportJSONFile);
                    displayJSONData(app.UITable, app.JSONData, app.amountOfLeds);
                    app.UITable.Visible = 'on';
                elseif(app.UITable.Visible == "on")
                    app.UITable.Visible = 'off';
                end
            catch
                app.UITable.Visible = 'off';
                disp("ERROR can't open file")
            end
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            app.UITable.Visible = 'off';
            cla(app.UIAxes_2)
            try
                if (app.UIAxes_2.Visible == "on")
                    app.UIAxes_2.Visible = 'off';
                elseif (app.UIAxes_2.Visible == "off")
                    app.UIAxes_2.Visible = 'on';
    
                    AdomeStructure(app.UIAxes_2);
                    hold(app.UIAxes_2,'on')
        
                    app.importJSONData(app.ExportJSONFile);
                    app.antennaArray = jsondecode(app.JSONData);
                    
                    for i = 1:length(app.antennaArray.Antennas)
                        theta{i} = app.antennaArray.Antennas(i).leds(1).theta;
                        phi{i} = app.antennaArray.Antennas(i).leds(1).phi;
                    end
        
                    theta = table2array(cell2table(theta));
                    phi = table2array(cell2table(phi));
    
                    AntennaConstellation(app.UIAxes_2, theta, phi);
        
                    %text(app.UIAxes, z, y, x, id); %see text directly in plot
                    hold(app.UIAxes_2,'off')
                end
            catch
            app.UIAxes_2.Visible = 'off';
            cla(app.UIAxes_2)
            disp("ERROR can't open file")
            end
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            receivedData = 'No data';
            
            app.openCOMPort();
            
            try
            if (app.TestcommandsListBox.Value == "Serial send")
                SerialSend(app.serial, app.SerialCommands.Value); % send the data in the EditField to the master of the nodes.
                receivedData = SerialRead(app.serial, "ASCII");
            elseif (app.TestcommandsListBox.Value == "Measurement")
                SerialSend(app.serial, 'M');
                receivedData = SerialRead(app.serial, "HEX");
            elseif (app.TestcommandsListBox.Value == "Localization")
                SerialSend(app.serial, 'C');
                receivedData = SerialRead(app.serial, "HEX");
            elseif (app.TestcommandsListBox.Value == "Test nodes")
                SerialSend(app.serial, 'T');
            elseif (app.TestcommandsListBox.Value == "Test serialcomm")
                SerialSend(app.serial, 'A');
                receivedData = SerialRead(app.serial, "ASCII");
            end
            catch
                return;
            end 
            
            disp(receivedData)
            %pause(0.1)
            app.serial = []; % close COM port
        end

        % Value changed function: AmountofLEDsEditField
        function AmountofLEDsEditFieldValueChanged(app, event)
            app.amountOfLeds = app.AmountofLEDsEditField.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.timer1)
            delete(app.timer1)
            diary off
            delete(app)
        end

        % Value changed function: SerialCommands
        function SerialCommandsValueChanged(app, event)
            app.SerialCommands.Value = app.SerialCommands.Value;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if (app.Switch.Value == "OCS")
                app.JSONFile = 'OCS/Adome_Antennas.json';
                app.ExportJSONFile = 'OCS/Adome_Antennas_export.json';
            elseif (app.Switch.Value == "Mock OCS")
                app.JSONFile = 'Mock_JSON/Adome_Antennas.json';
                app.ExportJSONFile = 'Mock_JSON/Adome_Antennas_export.json';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '3D')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.Visible = 'off';
            app.AmountofnodesEditFieldLabel = uilabel(app.UIFigure);
            app.AmountofnodesEditFieldLabel.Text = 'Amount of nodes';
            app.AmountofnodesEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmountofnodesEditField.ValueChangedFcn = createCallbackFcn(app, @AmountofnodesEditFieldValueChanged, true);
            app.AmountofnodesEditField.Value = 1;
            app.ListofcoordinatesButton = uibutton(app.UIFigure, 'state');
            app.ListofcoordinatesButton.ValueChangedFcn = createCallbackFcn(app, @ListofcoordinatesButtonValueChanged, true);
            app.ListofcoordinatesButton.Text = 'List of coordinates';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.Lamp_1 = uilamp(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3';
            app.Lamp_3 = uilamp(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Node'; 'LED'; 'Found'; 'Theta'; 'Phi'; 'x'; 'y'; 'z'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false false false];
            app.UITable.Visible = 'off';
            app.AmountofLEDsEditFieldLabel = uilabel(app.UIFigure);
            app.AmountofLEDsEditFieldLabel.Text = 'Amount of LED''s';
            app.AmountofLEDsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmountofLEDsEditField.ValueChangedFcn = createCallbackFcn(app, @AmountofLEDsEditFieldValueChanged, true);
            app.AmountofLEDsEditField.Value = 3;
            app.ViewButton = uibutton(app.UIFigure, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.TestcommandsListBoxLabel = uilabel(app.UIFigure);
            app.TestcommandsListBoxLabel.Text = 'Test commands';
            app.TestcommandsListBox = uilistbox(app.UIFigure);
            app.TestcommandsListBox.Items = {'Serial send', 'Measurement', 'Localization', 'Test nodes', 'Test serialcomm'};
            app.TestcommandsListBox.Value = 'Test serialcomm';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.TextArea = uitextarea(app.UIFigure);
            app.SerialCommands = uieditfield(app.UIFigure, 'text');
            app.SerialCommands.ValueChangedFcn = createCallbackFcn(app, @SerialCommandsValueChanged, true);
            app.ListOfCOMPorts = uilistbox(app.UIFigure);
            app.ListOfCOMPorts.Items = {'COM ports:'};
            app.ListOfCOMPorts.Value = 'COM ports:';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Mock OCS', 'OCS'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Mock OCS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdomeCalibration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end