classdef VisualizeNodesTestGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ShowdomeButton   matlab.ui.control.Button
        ShownodesButton  matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        JSONData;
        decodedJSONData;
        antennaArray;
    end
    
    methods (Access = public)
        
        function importJSONData(app, fileName) %Import and display the coordinates
            JSONFILE_name= sprintf(fileName);
            fid = fopen(JSONFILE_name,"r");
            app.JSONData = char(fread(fid))';
            fclose(fid);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes, 'equal');
            grid(app.UIAxes, 'on')

            xlim(app.UIAxes, [-1 1]);
            ylim(app.UIAxes, [-1 1]);
            zlim(app.UIAxes, [-1 1]);
        end

        % Button pushed function: ShownodesButton
        function ShownodesButtonPushed(app, event)
            % Define coordinates of each node
%             theta = [-7.1 -41.1 -37.1 -58.3 -58.3 -37.4 -40.7 -7.1 16.3 47.2 56.6 43.4 56.7 47.2 16.3 6.4 -16.5 -16.5 6.4 20.1 0];
%             phi = [148.4 136.9 113.7 101.6 78.4 66.2 43.3 31.6 49.2 49.2 71.0 90.0 108.9 130.8 130.8 109.1 101.6 78.4 70.9 90.0 90];
            app.importJSONData('Mock_JSON/Adome_Antennas.json');
            app.antennaArray = jsondecode(app.JSONData);
            

            for i = 1:length(app.antennaArray.Antennas)
                theta{i} = app.antennaArray.Antennas(i).leds(1).theta;
                phi{i} = app.antennaArray.Antennas(i).leds(1).phi;
            end
            theta = table2array(cell2table(theta));
            phi = table2array(cell2table(phi));
            
            for i = 1:length(theta)
                id{i} = i;
            end

            [x, y, z] = AntennaConstellation(theta, phi);
            
            nodes = plot3(app.UIAxes, z, y, x, '.','markersize', 20);
            dtRows = [dataTipTextRow("Id:",id),... 
                      dataTipTextRow("X:",z),... 
                      dataTipTextRow("Y:",y),... 
                      dataTipTextRow("Z:",x),...
                      dataTipTextRow("Theta:",theta),... 
                      dataTipTextRow("Phi:",phi)];
            nodes.DataTipTemplate.DataTipRows = dtRows;

            %text(app.UIAxes, z, y, x, id); %see text directly in plot
            hold(app.UIAxes,'on')
        end

        % Button pushed function: ShowdomeButton
        function ShowdomeButtonPushed(app, event)
            AdomeStructure(app);
            %plot3(app.UIAxes, x, y, z)
            
            hold(app.UIAxes,'on')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ShownodesButton = uibutton(app.UIFigure, 'push');
            app.ShownodesButton.ButtonPushedFcn = createCallbackFcn(app, @ShownodesButtonPushed, true);
            app.ShownodesButton.Text = 'Show nodes';
            app.ShowdomeButton = uibutton(app.UIFigure, 'push');
            app.ShowdomeButton.ButtonPushedFcn = createCallbackFcn(app, @ShowdomeButtonPushed, true);
            app.ShowdomeButton.Text = 'Show dome';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualizeNodesTestGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end